classdef App_Execution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Panel_Configuration            matlab.ui.Figure
        TabGroup3                      matlab.ui.container.TabGroup
        EjecucinTab                    matlab.ui.container.Tab
        NombredeEjecucinLabel_2        matlab.ui.control.Label
        Edit_NameFile_Sce              matlab.ui.control.EditField
        TopologaLabel                  matlab.ui.control.Label
        Edit_Topo                      matlab.ui.control.EditField
        TemperaturaMnimaLabel          matlab.ui.control.Label
        Edit_Tmin                      matlab.ui.control.EditField
        TemperaturaMximaLabel          matlab.ui.control.Label
        Edit_Tmax                      matlab.ui.control.EditField
        HumedadRelativaLabel           matlab.ui.control.Label
        Edit_HR                        matlab.ui.control.EditField
        RadiacinSolarLabel             matlab.ui.control.Label
        Edit_RS                        matlab.ui.control.EditField
        PrecipitacinLabel              matlab.ui.control.Label
        Edit_P                         matlab.ui.control.EditField
        VientoZonalLabel               matlab.ui.control.Label
        Edit_VZ                        matlab.ui.control.EditField
        VientoMeridionalLabel          matlab.ui.control.Label
        Edit_VM                        matlab.ui.control.EditField
        CaudalLquidoDescargadoLabel    matlab.ui.control.Label
        Edit_Q_LD                      matlab.ui.control.EditField
        FechaInicialLabel              matlab.ui.control.Label
        DateInit                       matlab.ui.control.DatePicker
        FechaFinalDatePickerLabel      matlab.ui.control.Label
        DateEnd                        matlab.ui.control.DatePicker
        CaudalLquidoCaptadoLabel       matlab.ui.control.Label
        Edit_Q_LC                      matlab.ui.control.EditField
        TiempodeArcillasMezcladasenDescargaLabel  matlab.ui.control.Label
        Edit_Arcillas                  matlab.ui.control.EditField
        TiempodeLimosMezcladosenDescargaLabel  matlab.ui.control.Label
        Edit_Limos                     matlab.ui.control.EditField
        TiempodeArenasMezcladasenDescargaLabel  matlab.ui.control.Label
        Edit_Arenas                    matlab.ui.control.EditField
        MovimientosenMasaLabel         matlab.ui.control.Label
        Edit_MM                        matlab.ui.control.EditField
        FactoresdeCalibracinLabel      matlab.ui.control.Label
        Edit_FacCal                    matlab.ui.control.EditField
        MuestreadordeResultadosLabel   matlab.ui.control.Label
        Edit_Muestr                    matlab.ui.control.EditField
        TasasdeRestauracinLabel        matlab.ui.control.Label
        Edit_Res                       matlab.ui.control.EditField
        SIGALabel                      matlab.ui.control.Label
        Button_Save                    matlab.ui.control.StateButton
        Image3                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        EscenarioLabel                 matlab.ui.control.Label
        Edit_NameSce                   matlab.ui.control.EditField
        Button_Cancel                  matlab.ui.control.StateButton
        Button_Info                    matlab.ui.control.StateButton
        CalibracinTab                  matlab.ui.container.Tab
        Table_Met                      matlab.ui.control.Table
        Table_Veg                      matlab.ui.control.Table
        FactoresdelModeloMeteorolgicoLabel  matlab.ui.control.Label
        FactoresdelModelodeRestauracindelaVegetacinLabel  matlab.ui.control.Label
        Table_Fen                      matlab.ui.control.Table
        FactoresdelModelodeFenolgicoLabel  matlab.ui.control.Label
        Table_Hid                      matlab.ui.control.Table
        FactoresdelModeloHidrolgicoLabel  matlab.ui.control.Label
        Table_Sed                      matlab.ui.control.Table
        FactoresdelModeloSedimentolgicoLabel  matlab.ui.control.Label
        Table_Geo                      matlab.ui.control.Table
        FactoresdelModeloGeotcnicoLabel  matlab.ui.control.Label
        NombredelArchivodeCalibracinLabel  matlab.ui.control.Label
        Edit_NameCal                   matlab.ui.control.EditField
        MuestreadorTab                 matlab.ui.container.Tab
        Table_VarPointControl          matlab.ui.control.Table
        ListodeVariablesParaPuntosdeControlLabel  matlab.ui.control.Label
        Table_PointControl             matlab.ui.control.Table
        ListadodePuntosdeControlLabel  matlab.ui.control.Label
        Table_VarBranchControl         matlab.ui.control.Table
        ListadodeVariablesParaTramosdeControlLabel  matlab.ui.control.Label
        Table_VarMap                   matlab.ui.control.Table
        ListadodeVariablesParaMapasLabel  matlab.ui.control.Label
        Table_BranchControl            matlab.ui.control.Table
        ListadodeTramosdeControlLabel  matlab.ui.control.Label
        NombredelArchivodeMuestreadorLabel  matlab.ui.control.Label
        Edit_NameMues                  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        PathProject
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, StatusNew, PathProject, NameFileSce)
            
            app.PathProject = PathProject;
                        
            if StatusNew               
                % Calibración
                app.Table_Met.Data = repmat(-9999,1,17);
                app.Table_Veg.Data = repmat(-9999,34,4);
                app.Table_Fen.Data = repmat(-9999,42,21);
                app.Table_Hid.Data = repmat(-9999,1,17);
                app.Table_Sed.Data = repmat(-9999,1,17);
                app.Table_Geo.Data = repmat(-9999,1,17);
                
                % Muestreador
                app.Table_VarPointControl.Data  = logical(ones(1,130));
                app.Table_VarBranchControl.Data = logical(ones(1,130));
                app.Table_VarMap.Data           = logical(ones(1,130));
                Tmp1 = cell(30,4);
                Tmp2 = cell(30,5);
                app.Table_PointControl.Data     = Tmp1;
                app.Table_BranchControl.Data    = Tmp2;
            else
                % Leer Achivo de Ejecución 
                PathFile = fullfile(PathProject,'entradas','ejecucion',NameFileSce);
                UserData = Read_ExecutionFile(PathFile);
                
                % Asignar valores a los campos de texto de la App
                app.Edit_NameFile_Sce.Value  = NameFileSce;
                app.Edit_NameSce.Value  = UserData.NameSce;
                app.DateInit.Value      = UserData.DateInit;
                app.DateEnd.Value       = UserData.DateEnd;
                app.Edit_Topo.Value     = UserData.Topo;
                app.Edit_Tmin.Value     = UserData.Tmin;
                app.Edit_Tmax.Value     = UserData.Tmax;
                app.Edit_HR.Value       = UserData.HR;
                app.Edit_RS.Value       = UserData.RS;
                app.Edit_P.Value        = UserData.P;
                app.Edit_VZ.Value       = UserData.VZ;
                app.Edit_VM.Value       = UserData.VM;
                app.Edit_Q_LD.Value     = UserData.Q_LD;
                app.Edit_Q_LC.Value     = UserData.Q_LC;
                app.Edit_Arcillas.Value = UserData.Arcillas;
                app.Edit_Limos.Value    = UserData.Limos;
                app.Edit_Arenas.Value   = UserData.Arenas;
                app.Edit_MM.Value       = UserData.MM;
                app.Edit_FacCal.Value   = UserData.F_Cal;
                app.Edit_Muestr.Value   = UserData.M_Results;
                app.Edit_Res.Value      = UserData.Time_Res;
                
                % Leer Archivo de Parametros
                PathFile = fullfile(PathProject,'entradas','calibracion',UserData.F_Cal);
                UserData_Params = Read_CalibrationFile(PathFile);
                
                % Asignar valores a los campos de texto de la App
                app.Edit_NameCal.Value = UserData.F_Cal;
                app.Table_Met.Data = UserData_Params.Met;
                app.Table_Veg.Data = UserData_Params.Veg;
                app.Table_Fen.Data = UserData_Params.Fen;
                app.Table_Hid.Data = UserData_Params.Hid;
                app.Table_Sed.Data = UserData_Params.Sed;
                app.Table_Geo.Data = UserData_Params.Geo;
                
                % Leer Archivo de Muestreador
                PathFile = fullfile(PathProject,'entradas','muestras',UserData.M_Results);
                UserData_M = Read_MuestreadorFile(PathFile);
                
                % Asignar valores a los campos de texto de la App 
                app.Edit_NameMues.Value         = UserData.M_Results;                
                app.Table_VarPointControl.Data  = UserData_M.Var_PC;
                app.Table_VarBranchControl.Data = UserData_M.Var_TC;
                app.Table_VarMap.Data           = UserData_M.Var_Map; 
                
                Tmp = cell(50,4);
                for i = 1:UserData_M.Num_PC
                    Tmp(i,:) = UserData_M.PC(i,:);
                end
                app.Table_PointControl.Data = Tmp;
                
                Tmp = cell(50,5);
                for i = 1:UserData_M.Num_TC
                    Tmp(i,:) = UserData_M.TC(i,:);
                end
                app.Table_BranchControl.Data = Tmp;
            end
        end

        % Value changed function: Button_Save
        function Button_SaveValueChanged(app, event)
            PathProject = app.PathProject;
            
            app.Button_Save.Value = 0;            

            % Leer Datos de Ejecución
            NameFileSce         = app.Edit_NameFile_Sce.Value;
            UserData.NameSce    = app.Edit_NameSce.Value;
            UserData.dt         = 1.0;
            UserData.NameSce    = app.Edit_NameFile_Sce.Value;
            UserData.DateInit   = app.DateInit.Value;
            UserData.DateEnd    = app.DateEnd.Value;
            UserData.Topo       = app.Edit_Topo.Value ;
            UserData.Tmin       = app.Edit_Tmin.Value;
            UserData.Tmax       = app.Edit_Tmax.Value;
            UserData.HR         = app.Edit_HR.Value;
            UserData.RS         = app.Edit_RS.Value;
            UserData.P          = app.Edit_P.Value;
            UserData.VZ         = app.Edit_VZ.Value;
            UserData.VM         = app.Edit_VM.Value;
            UserData.Q_LD       = app.Edit_Q_LD.Value;
            UserData.Q_LC       = app.Edit_Q_LC.Value;
            UserData.Arcillas   = app.Edit_Arcillas.Value;
            UserData.Limos      = app.Edit_Limos.Value;
            UserData.Arenas     = app.Edit_Arenas.Value;
            UserData.MM         = app.Edit_MM.Value;
            UserData.F_Cal      = app.Edit_FacCal.Value;
            UserData.M_Results  = app.Edit_Muestr.Value;
            UserData.Time_Res   = app.Edit_Res.Value;
            
            % Guardar Archivo de ejecución nuevo
            PathFile = fullfile(PathProject,'entradas','ejecucion',NameFileSce);
            Write_ExecutionFile(PathFile, UserData);
            
            % Leer Datos de calibración            
            UserData.F_Cal      = app.Edit_NameCal.Value;
            UserData_Params.Met = app.Table_Met.Data;
            UserData_Params.Veg = app.Table_Veg.Data;
            UserData_Params.Fen = app.Table_Fen.Data;
            UserData_Params.Hid = app.Table_Hid.Data;
            UserData_Params.Sed = app.Table_Sed.Data;
            UserData_Params.Geo = app.Table_Geo.Data;
            
            % Guardar Archivo de Parametros
            PathFile = fullfile(PathProject,'entradas','calibracion',UserData.F_Cal);
            Write_CalibrationFile(PathFile, UserData_Params);
            
            % Leer Datos de Muestreador
            UserData.M_Results = app.Edit_NameMues.Value;                
            UserData_M.Var_PC  = app.Table_VarPointControl.Data;
            UserData_M.Var_TC  = app.Table_VarBranchControl.Data;
            UserData_M.Var_Map = app.Table_VarMap.Data;
            
            Tmp = app.Table_PointControl.Data;
            id = find(~isnan(cell2mat(Tmp(:,1))));
            UserData_M.PC = Tmp(id,:);
            
            Tmp = app.Table_BranchControl.Data;
            id = find(~isnan(cell2mat(Tmp(:,1))));
            UserData_M.TC = Tmp(id,:);
            
            % Guardar Archivo de Muestreador
            PathFile = fullfile(PathProject,'entradas','muestras',UserData.M_Results);
            Write_MuestreadosFile(PathFile, UserData_M);
            
            % Cerrar Panel de Configuración
            close(app.Panel_Configuration)
        end

        % Value changed function: Edit_FacCal
        function Edit_FacCalValueChanged(app, event)
            app.Edit_NameCal.Value = app.Edit_FacCal.Value;            
        end

        % Value changed function: Edit_Muestr
        function Edit_MuestrValueChanged(app, event)
            app.Edit_NameMues.Value = app.Edit_Muestr.Value;            
        end

        % Value changed function: Button_Cancel
        function Button_CancelValueChanged(app, event)
            % Cerrar Panel de Configuración
            app.Button_Cancel.Value = 0;
            close(app.Panel_Configuration)            
        end

        % Value changed function: Button_Info
        function Button_InfoValueChanged(app, event)
            app.Button_Info.Value = 0;
            
            % Manual
            system('explorer https://tnc.box.com/s/jlj8oq3tlpz0rjup8fx2sxzn27ry9lly')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Panel_Configuration = uifigure('Visible', 'off');
            app.Panel_Configuration.Name = 'UI Figure';
            app.TabGroup3 = uitabgroup(app.Panel_Configuration);
            app.EjecucinTab = uitab(app.TabGroup3);
            app.EjecucinTab.Title = 'Ejecución';
            app.NombredeEjecucinLabel_2 = uilabel(app.EjecucinTab);
            app.NombredeEjecucinLabel_2.Text = 'Nombre de Ejecución';
            app.Edit_NameFile_Sce = uieditfield(app.EjecucinTab, 'text');
            app.Edit_NameFile_Sce.Value = 'NA';
            app.TopologaLabel = uilabel(app.EjecucinTab);
            app.TopologaLabel.Text = 'Topología(*)';
            app.Edit_Topo = uieditfield(app.EjecucinTab, 'text');
            app.Edit_Topo.Value = 'NA';
            app.TemperaturaMnimaLabel = uilabel(app.EjecucinTab);
            app.TemperaturaMnimaLabel.Text = {'Temperatura'; 'Mínima (*)'};
            app.Edit_Tmin = uieditfield(app.EjecucinTab, 'text');
            app.Edit_Tmin.Value = 'NA';
            app.TemperaturaMximaLabel = uilabel(app.EjecucinTab);
            app.TemperaturaMximaLabel.Text = {'Temperatura'; 'Máxima (*)'};
            app.Edit_Tmax = uieditfield(app.EjecucinTab, 'text');
            app.Edit_Tmax.Value = 'NA';
            app.HumedadRelativaLabel = uilabel(app.EjecucinTab);
            app.HumedadRelativaLabel.Text = {'Humedad'; 'Relativa (*)'; ''};
            app.Edit_HR = uieditfield(app.EjecucinTab, 'text');
            app.Edit_HR.Value = 'NA';
            app.RadiacinSolarLabel = uilabel(app.EjecucinTab);
            app.RadiacinSolarLabel.Text = {'Radiación'; 'Solar (*)'};
            app.Edit_RS = uieditfield(app.EjecucinTab, 'text');
            app.Edit_RS.Value = 'NA';
            app.PrecipitacinLabel = uilabel(app.EjecucinTab);
            app.PrecipitacinLabel.Text = 'Precipitación (*)';
            app.Edit_P = uieditfield(app.EjecucinTab, 'text');
            app.Edit_P.Value = 'NA';
            app.VientoZonalLabel = uilabel(app.EjecucinTab);
            app.VientoZonalLabel.Text = {'Viento'; 'Zonal (*)'};
            app.Edit_VZ = uieditfield(app.EjecucinTab, 'text');
            app.Edit_VZ.Editable = 'off';
            app.Edit_VZ.Value = 'NA';
            app.VientoMeridionalLabel = uilabel(app.EjecucinTab);
            app.VientoMeridionalLabel.Text = {'Viento'; 'Meridional (*)'};
            app.Edit_VM = uieditfield(app.EjecucinTab, 'text');
            app.Edit_VM.Value = 'NA';
            app.CaudalLquidoDescargadoLabel = uilabel(app.EjecucinTab);
            app.CaudalLquidoDescargadoLabel.Text = {'Caudal Líquido'; 'Descargado'};
            app.Edit_Q_LD = uieditfield(app.EjecucinTab, 'text');
            app.Edit_Q_LD.Value = 'NA';
            app.FechaInicialLabel = uilabel(app.EjecucinTab);
            app.FechaInicialLabel.Text = 'Fecha Inicial';
            app.DateInit = uidatepicker(app.EjecucinTab);
            app.DateInit.Limits = [datetime([1900 1 1]) datetime([9999 12 31])];
            app.FechaFinalDatePickerLabel = uilabel(app.EjecucinTab);
            app.FechaFinalDatePickerLabel.Text = 'Fecha Final';
            app.DateEnd = uidatepicker(app.EjecucinTab);
            app.DateEnd.Limits = [datetime([1900 1 1]) datetime([9999 12 31])];
            app.CaudalLquidoCaptadoLabel = uilabel(app.EjecucinTab);
            app.CaudalLquidoCaptadoLabel.Text = {'Caudal Líquido'; 'Captado'};
            app.Edit_Q_LC = uieditfield(app.EjecucinTab, 'text');
            app.Edit_Q_LC.Value = 'NA';
            app.TiempodeArcillasMezcladasenDescargaLabel = uilabel(app.EjecucinTab);
            app.TiempodeArcillasMezcladasenDescargaLabel.Text = {'Tiempo de Arcillas'; 'Mezcladas en Descarga'};
            app.Edit_Arcillas = uieditfield(app.EjecucinTab, 'text');
            app.Edit_Arcillas.Value = 'NA';
            app.TiempodeLimosMezcladosenDescargaLabel = uilabel(app.EjecucinTab);
            app.TiempodeLimosMezcladosenDescargaLabel.Text = {'Tiempo de  Limos'; 'Mezclados en Descarga'};
            app.Edit_Limos = uieditfield(app.EjecucinTab, 'text');
            app.Edit_Limos.Value = 'NA';
            app.TiempodeArenasMezcladasenDescargaLabel = uilabel(app.EjecucinTab);
            app.TiempodeArenasMezcladasenDescargaLabel.Text = {'Tiempo de Arenas'; 'Mezcladas en Descarga'};
            app.Edit_Arenas = uieditfield(app.EjecucinTab, 'text');
            app.Edit_Arenas.Value = 'NA';
            app.MovimientosenMasaLabel = uilabel(app.EjecucinTab);
            app.MovimientosenMasaLabel.Text = {'Movimientos'; 'en Masa (*)'};
            app.Edit_MM = uieditfield(app.EjecucinTab, 'text');
            app.Edit_MM.Value = 'NA';
            app.FactoresdeCalibracinLabel = uilabel(app.EjecucinTab);
            app.FactoresdeCalibracinLabel.Text = {'Factores de'; 'Calibración (*)'};
            app.Edit_FacCal = uieditfield(app.EjecucinTab, 'text');
            app.Edit_FacCal.ValueChangedFcn = createCallbackFcn(app, @Edit_FacCalValueChanged, true);
            app.Edit_FacCal.Value = 'NA';
            app.MuestreadordeResultadosLabel = uilabel(app.EjecucinTab);
            app.MuestreadordeResultadosLabel.Text = {'Muestreador'; 'de Resultados (*)'};
            app.Edit_Muestr = uieditfield(app.EjecucinTab, 'text');
            app.Edit_Muestr.ValueChangedFcn = createCallbackFcn(app, @Edit_MuestrValueChanged, true);
            app.Edit_Muestr.Value = 'NA';
            app.TasasdeRestauracinLabel = uilabel(app.EjecucinTab);
            app.TasasdeRestauracinLabel.Text = {'Tasas de '; 'Restauración (*)'};
            app.Edit_Res = uieditfield(app.EjecucinTab, 'text');
            app.Edit_Res.Value = 'NA';
            app.SIGALabel = uilabel(app.EjecucinTab);
            app.SIGALabel.Text = 'SIGA';
            app.Button_Save = uibutton(app.EjecucinTab, 'state');
            app.Button_Save.ValueChangedFcn = createCallbackFcn(app, @Button_SaveValueChanged, true);
            app.Button_Save.Icon = 'Icons_Save.png';
            app.Button_Save.Text = 'Guardar';
            app.Image3 = uiimage(app.EjecucinTab);
            app.Image3.ImageSource = 'CI.png';
            app.Image = uiimage(app.EjecucinTab);
            app.Image.ImageSource = 'Icons_TNC.png';
            app.Image2 = uiimage(app.EjecucinTab);
            app.Image2.ImageSource = 'Icons_Gotta.jpg';
            app.EscenarioLabel = uilabel(app.EjecucinTab);
            app.EscenarioLabel.Text = 'Escenario';
            app.Edit_NameSce = uieditfield(app.EjecucinTab, 'text');
            app.Edit_NameSce.Value = 'NA';
            app.Button_Cancel = uibutton(app.EjecucinTab, 'state');
            app.Button_Cancel.ValueChangedFcn = createCallbackFcn(app, @Button_CancelValueChanged, true);
            app.Button_Cancel.Icon = 'Cancel.png';
            app.Button_Cancel.Text = 'Cancelar';
            app.Button_Info = uibutton(app.EjecucinTab, 'state');
            app.Button_Info.ValueChangedFcn = createCallbackFcn(app, @Button_InfoValueChanged, true);
            app.Button_Info.Icon = 'Info.png';
            app.Button_Info.Text = '';
            app.CalibracinTab = uitab(app.TabGroup3);
            app.CalibracinTab.Title = 'Calibración';
            app.Table_Met = uitable(app.CalibracinTab);
            app.Table_Met.ColumnName = {'minTAmin'; 'maxTAmin'; 'minTAmax'; 'maxTAmax'; 'minHR'; 'maxHR'; 'minRS'; 'maxRS'; 'minPV'; 'maxPV'; 'minUx'; 'maxUx'; 'minUy'; 'maxUy'; 'p'; 'Ez1'; 'Ez2'};
            app.Table_Met.RowName = {''};
            app.Table_Met.ColumnEditable = true;
            app.Table_Veg = uitable(app.CalibracinTab);
            app.Table_Veg.ColumnName = {'Transicion'; 'CLC'; 'Deltatm'; 'Accion'};
            app.Table_Veg.RowName = {''};
            app.Table_Veg.ColumnEditable = true;
            app.FactoresdelModeloMeteorolgicoLabel = uilabel(app.CalibracinTab);
            app.FactoresdelModeloMeteorolgicoLabel.Text = 'Factores del Modelo Meteorológico';
            app.FactoresdelModelodeRestauracindelaVegetacinLabel = uilabel(app.CalibracinTab);
            app.FactoresdelModelodeRestauracindelaVegetacinLabel.Text = 'Factores del Modelo de Restauración de la Vegetación';
            app.Table_Fen = uitable(app.CalibracinTab);
            app.Table_Fen.ColumnName = {'CLC'; 'psi'; 'theta'; 'n2'; 'C_usle'; 'LAImax'; 'LAImin'; 'PHU'; 'l1'; 'l2'; 'l3'; 'frPHUsen'; 'SMIc'; 'nSMI'; 'TAinf'; 'TAsup'; 'SOS1'; 'SOS2'; 'KCmin'; 'KCmax'; 'etah'};
            app.Table_Fen.RowName = {''};
            app.Table_Fen.ColumnEditable = true;
            app.FactoresdelModelodeFenolgicoLabel = uilabel(app.CalibracinTab);
            app.FactoresdelModelodeFenolgicoLabel.Text = 'Factores del Modelo de Fenológico';
            app.Table_Hid = uitable(app.CalibracinTab);
            app.Table_Hid.ColumnName = {'fc_PV'; 'fc_PH'; 'fc_SU0'; 'fc_SU1'; 'fc_SU3'; 'fc_ks'; 'fc_kp'; 'fc_ki'; 'fc_U2'; 'fc_U3'; 'fc_U4'; 'fc_U5'; 'us'; 'ui'};
            app.Table_Hid.RowName = {''};
            app.Table_Hid.ColumnEditable = true;
            app.FactoresdelModeloHidrolgicoLabel = uilabel(app.CalibracinTab);
            app.FactoresdelModeloHidrolgicoLabel.Text = 'Factores del Modelo Hidrológico';
            app.Table_Sed = uitable(app.CalibracinTab);
            app.Table_Sed.ColumnName = {'fc_E2Smax'; 'fc_E5Smax'; 'Bx'; 'b'; 'Us_arc'; 'Us_lim'; 'Us_are'; 'Ds_arc'; 'Ds_lim'; 'Ds_are'; 'Gs'; 'Rs'};
            app.Table_Sed.RowName = {''};
            app.Table_Sed.ColumnEditable = true;
            app.FactoresdelModeloSedimentolgicoLabel = uilabel(app.CalibracinTab);
            app.FactoresdelModeloSedimentolgicoLabel.Text = 'Factores del Modelo Sedimentológico';
            app.Table_Geo = uitable(app.CalibracinTab);
            app.Table_Geo.ColumnName = {'fc_c'; 'fc_phi'; 'fc_rhoB'; 'fc_HS'; 'alfa_crit'; 'alfa_stop'; 'a_stop'};
            app.Table_Geo.RowName = {''};
            app.Table_Geo.ColumnEditable = true;
            app.FactoresdelModeloGeotcnicoLabel = uilabel(app.CalibracinTab);
            app.FactoresdelModeloGeotcnicoLabel.Text = 'Factores del Modelo Geotécnico';
            app.NombredelArchivodeCalibracinLabel = uilabel(app.CalibracinTab);
            app.NombredelArchivodeCalibracinLabel.Text = 'Nombre del Archivo de Calibración';
            app.Edit_NameCal = uieditfield(app.CalibracinTab, 'text');
            app.Edit_NameCal.Editable = 'off';
            app.Edit_NameCal.Value = 'Calibration.txt';
            app.MuestreadorTab = uitab(app.TabGroup3);
            app.MuestreadorTab.Title = 'Muestreador';
            app.Table_VarPointControl = uitable(app.MuestreadorTab);
            app.Table_VarPointControl.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'HR'; 'RS'; 'PV'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PH'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zwcrit'; 'Zmin'; 'Zmax'; 'Smin'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'};
            app.Table_VarPointControl.RowName = {''};
            app.Table_VarPointControl.ColumnEditable = true;
            app.ListodeVariablesParaPuntosdeControlLabel = uilabel(app.MuestreadorTab);
            app.ListodeVariablesParaPuntosdeControlLabel.Text = 'Listo de Variables Para Puntos de Control';
            app.Table_PointControl = uitable(app.MuestreadorTab);
            app.Table_PointControl.ColumnName = {'X'; 'Y'; 'Nombre'; 'Estadístico'};
            app.Table_PointControl.RowName = {''};
            app.Table_PointControl.ColumnEditable = true;
            app.ListadodePuntosdeControlLabel = uilabel(app.MuestreadorTab);
            app.ListadodePuntosdeControlLabel.Text = 'Listado de Puntos de Control';
            app.Table_VarBranchControl = uitable(app.MuestreadorTab);
            app.Table_VarBranchControl.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'HR'; 'RS'; 'PV'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PH'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zwcrit'; 'Zmin'; 'Zmax'; 'Smin'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'};
            app.Table_VarBranchControl.RowName = {''};
            app.Table_VarBranchControl.ColumnEditable = true;
            app.ListadodeVariablesParaTramosdeControlLabel = uilabel(app.MuestreadorTab);
            app.ListadodeVariablesParaTramosdeControlLabel.Text = 'Listado de Variables Para Tramos de Control';
            app.Table_VarMap = uitable(app.MuestreadorTab);
            app.Table_VarMap.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'HR'; 'RS'; 'PV'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PH'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zwcrit'; 'Zmin'; 'Zmax'; 'Smin'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'};
            app.Table_VarMap.RowName = {''};
            app.Table_VarMap.ColumnEditable = true;
            app.ListadodeVariablesParaMapasLabel = uilabel(app.MuestreadorTab);
            app.ListadodeVariablesParaMapasLabel.Text = 'Listado de Variables Para Mapas';
            app.Table_BranchControl = uitable(app.MuestreadorTab);
            app.Table_BranchControl.ColumnName = {'X1'; 'X1'; 'X2'; 'Y2'; 'Nombre del Tramo'};
            app.Table_BranchControl.RowName = {''};
            app.Table_BranchControl.ColumnEditable = true;
            app.ListadodeTramosdeControlLabel = uilabel(app.MuestreadorTab);
            app.ListadodeTramosdeControlLabel.Text = 'Listado de Tramos de Control';
            app.NombredelArchivodeMuestreadorLabel = uilabel(app.MuestreadorTab);
            app.NombredelArchivodeMuestreadorLabel.Text = 'Nombre del Archivo de Muestreador';
            app.Edit_NameMues = uieditfield(app.MuestreadorTab, 'text');
            app.Edit_NameMues.Editable = 'off';
            app.Edit_NameMues.Value = 'Calibration.txt';
            % Show the figure after all components are created
            app.Panel_Configuration.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Execution(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Panel_Configuration)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Panel_Configuration)
        end
    end
end