classdef App_FinModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Escenario1DropDownLabel        matlab.ui.control.Label
        List_Sce_1                     matlab.ui.control.DropDown
        Escenario2DropDown_2Label      matlab.ui.control.Label
        List_Sce_2                     matlab.ui.control.DropDown
        EntradasModeloFinancieroLabel  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        Logo_TNC                       matlab.ui.control.Image
        Logo_CI                        matlab.ui.control.Image
        Logo_Gotta                     matlab.ui.control.Image
        EjecucinLabel                  matlab.ui.control.Label
        List_Exe_1                     matlab.ui.control.DropDown
        EjecucinLabel_2                matlab.ui.control.Label
        List_Exe_2                     matlab.ui.control.DropDown
        ReservorioLabel                matlab.ui.control.Label
        List_Place_R                   matlab.ui.control.DropDown
        PuntoLabel                     matlab.ui.control.Label
        List_Place_P                   matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        PathProject % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PathProject)
            app.PathProject = PathProject;
            
            % Leer carpetas de Scenarios
            d           = dir(fullfile(PathProject,'salidas'));
            dfolders    = d([d(:).isdir]);
            dfolders    = dfolders(~ismember({dfolders(:).name},{'.','..'}));
            Tmp         = {'None',dfolders.name}';
            app.List_Sce_1.Items = Tmp;
            app.List_Sce_1.Value = Tmp{1};
            
            app.List_Sce_2.Items = Tmp;
            app.List_Sce_2.Value = Tmp{1};
            
        end

        % Value changed function: List_Sce_1
        function List_Sce_1ValueChanged(app, event)
            
            PathProject =  app.PathProject;            
            value = app.List_Sce_1.Value;
            
            if ~strcmp(value,'None')                                
                % Listar ejecuciones
                d = dir(fullfile(PathProject,'salidas',value));                    
                dfolders = d([d(:).isdir]);            
                dfolders = dfolders(~ismember({dfolders(:).name},{'.','..'}));
                Tmp      = {'None',dfolders.name}';
                app.List_Exe_1.Items = Tmp;
                app.List_Exe_1.Value = Tmp{1};
                
                % Desabilitar
                app.List_Exe_1.Enable = true;
            else
                % Habilitar
                app.List_Exe_1.Enable     = false;
                
            end
            
        end

        % Value changed function: List_Sce_2
        function List_Sce_2ValueChanged(app, event)
            
            value = app.List_Sce_2.Value;            
            PathProject =  app.PathProject;            
            
            if ~strcmp(value,'None')                                
                % Listar ejecuciones
                d = dir(fullfile(PathProject,'salidas',value));                    
                dfolders = d([d(:).isdir]);            
                dfolders = dfolders(~ismember({dfolders(:).name},{'.','..'}));
                Tmp      = {'None',dfolders.name}';
                app.List_Exe_2.Items = Tmp;
                app.List_Exe_2.Value = Tmp{1};
                
                % Desabilitar
                app.List_Exe_2.Enable = true;
            else
                % Habilitar
                app.List_Exe_2.Enable     = false;
                
            end
                       
        end

        % Value changed function: List_Exe_1
        function List_Exe_1ValueChanged(app, event)
            value = app.List_Exe_1.Value;
            value1 = app.List_Exe_2.Value;
            
            PathProject =  app.PathProject;            
            
            if ~strcmp(value,'None') && ~strcmp(value1,'None')
                % Listar Series
                NameSce = app.List_Sce_1.Value;
                NameFiles = dir(fullfile(PathProject,'salidas',NameSce, value,'series','*.txt'));
                NameFiles = {'None',NameFiles.name}';
                
                app.List_Place_R.Items   = NameFiles;
                app.List_Place_R.Value   = NameFiles{1};
                app.List_Place_P.Items   = NameFiles;
                app.List_Place_P.Value   = NameFiles{1};
                
                % Habilitar
                app.List_Place_R.Enable = true;
                app.List_Place_P.Enable = true;
                
            else
                % Desabilitar                
                app.List_Place_R.Enable   = false;
                app.List_Place_P.Enable   = false;
                
            end
            
        end

        % Value changed function: List_Exe_2
        function List_Exe_2ValueChanged(app, event)
            value = app.List_Exe_1.Value;
            value1 = app.List_Exe_2.Value;
            
            PathProject =  app.PathProject;            
            
            if ~strcmp(value,'None') && ~strcmp(value1,'None')
                % Listar Series
                NameSce = app.List_Sce_1.Value;
                NameFiles = dir(fullfile(PathProject,'salidas',NameSce, value,'series','*.txt'));
                NameFiles = {'None',NameFiles.name}';
                
                app.List_Place_R.Items = NameFiles;
                app.List_Place_R.Value = NameFiles{1};
                app.List_Place_P.Items = NameFiles;
                app.List_Place_P.Value = NameFiles{1};
                
                % Habilitar
                app.List_Place_R.Enable = true;
                app.List_Place_P.Enable = true;
                
            else
                % Desabilitar                
                app.List_Place_R.Enable = false;
                app.List_Place_P.Enable   = false;
                
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Leer Parametros   
            PathProject = app.PathProject; 
                        
            ProgressBar = waitbar(0, 'Ejecutando Modelo Financiero ...');
            wbch        = allchild(ProgressBar);
            jp          = wbch(1).JavaPeer;
            jp.setIndeterminate(1)
                
            %% Reservorio Linea Base Sce1
            % --------------------------------
            NameSce     = app.List_Sce_1.Value;
            NameExe     = app.List_Exe_1.Value;
            NamePlace   = app.List_Place_R.Value;
            
            PathFile    = fullfile(PathProject,'salidas',NameSce, NameExe,'series',NamePlace);
            
            Data = Read_TimeSeries_SIGA(PathFile);
            id   = [find(strcmp(Data.NameVar,'Q5(m3.d-1)')), find(strcmp(Data.NameVar,'ESDET(m3)'))];
            
            Resolution = 'Mensual';

            Results = Aggregation_TimeSeries(Data, Resolution, 'Promedio');           
            Results_Sce_R_1(:,1) = Results.Data(:,id(1))./(24*3600);
            
            Results = Aggregation_TimeSeries(Data, Resolution, 'Suma');           
            Results_Sce_R_1(:,2) = Results.Data(:,id(2))*(2.65);
            
            % Energy
            PathFile    = fullfile(PathProject,'salidas',NameSce, NameExe,'series',['Energy_', NamePlace]);
            
            Data = Read_TimeSeries_SIGA(PathFile);
            id   = find(strcmp(Data.NameVar,'Energia'));
            
            Resolution = 'Mensual';
            
            Results = Aggregation_TimeSeries(Data, Resolution, 'Promedio');           
            Results_Sce_R_1(:,3) = Results.Data(:,id);
            
            
            %% Reservorio Linea Base Sce2
            % --------------------------------
            NameSce     = app.List_Sce_2.Value;
            NameExe     = app.List_Exe_2.Value;
            NamePlace   = app.List_Place_R.Value;
            
            PathFile    = fullfile(PathProject,'salidas',NameSce, NameExe,'series',NamePlace);
            
            Data = Read_TimeSeries_SIGA(PathFile);
            id   = [find(strcmp(Data.NameVar,'Q5(m3.d-1)')), find(strcmp(Data.NameVar,'ESDET(m3)'))];
            
            Resolution = 'Mensual';

            Results = Aggregation_TimeSeries(Data, Resolution, 'Promedio');           
            Results_Sce_R_2(:,1) = Results.Data(:,id(1))./(24*3600);
            
            Results = Aggregation_TimeSeries(Data, Resolution, 'Suma');           
            Results_Sce_R_2(:,2) = Results.Data(:,id(2))*(2.65);
            
            % Energy
            PathFile    = fullfile(PathProject,'salidas',NameSce, NameExe,'series',['Energy_', NamePlace]);
            
            Data = Read_TimeSeries_SIGA(PathFile);
            id   = find(strcmp(Data.NameVar,'Energia'));
            
            Resolution = 'Mensual';
            
            Results = Aggregation_TimeSeries(Data, Resolution, 'Promedio');           
            Results_Sce_R_2(:,3) = Results.Data(:,id);            
            
            %% Scenario 1 Points
            % --------------------------------
            NameSce     = app.List_Sce_1.Value;
            NameExe     = app.List_Exe_1.Value;
            NamePlace   = app.List_Place_P.Value;
            
            PathFile    = fullfile(PathProject,'salidas',NameSce, NameExe,'series',NamePlace);
            
            Data = Read_TimeSeries_SIGA(PathFile);
            id   = [find(strcmp(Data.NameVar,'Q5(m3.d-1)')), find(strcmp(Data.NameVar,'ESDET(m3)'))];
            
            Resolution = 'Mensual';

            Results = Aggregation_TimeSeries(Data, Resolution, 'Promedio');           
            Results_Sce_P_1(:,1) = Results.Data(:,id(1))./(24*3600);
            
            Results = Aggregation_TimeSeries(Data, Resolution, 'Suma');           
            Results_Sce_P_1(:,2) = Results.Data(:,id(2))*(2.65);                                   
            
            %% Scenario 2
            % ---------------------------------
            NameSce     = app.List_Sce_2.Value;
            NameExe     = app.List_Exe_2.Value;
            NamePlace   = app.List_Place_P.Value;
            
            PathFile    = fullfile(PathProject,'salidas',NameSce, NameExe,'series',NamePlace);
            
            Data = Read_TimeSeries_SIGA(PathFile);
            id   = [find(strcmp(Data.NameVar,'Q5(m3.d-1)')), find(strcmp(Data.NameVar,'ESDET(m3)'))];
            
            Resolution = 'Mensual';

            Results = Aggregation_TimeSeries(Data, Resolution, 'Promedio');           
            Results_Sce_P_2(:,1) = Results.Data(:,id(1))./(24*3600);
            
            Results = Aggregation_TimeSeries(Data, Resolution, 'Suma');           
            Results_Sce_P_2(:,2) = Results.Data(:,id(2))*(2.65);            
            
            
            %% Export
            % --------------------------------
            NameFile = uigetfile({'*.xlsx';'*.xls';'*.xlsm'}, 'Seleccionar Modelo Financiero');
            
            if NameFile == 0  
                close(ProgressBar)
                msgbox('No se a seleccionado el modelo financiero')
                return
            end

            Excel       = actxserver('Excel.Application');
            Workbook    = Excel.Workbooks.Open(NameFile);
            Worksheet   = Workbook.Worksheets.Item('Biophysical & energy inputs');
            
            Excel.Range("B20:B1000").Select;
            % Clear the cell contents.
            Excel.Selection.Clear;
            
            Excel.Range("D20:D1000").Select;
            % Clear the cell contents.
            Excel.Selection.Clear;
            
            Excel.Range("O20:P1000").Select;
            % Clear the cell contents.
            Excel.Selection.Clear;
            
            Excel.Range("R20:R1000").Select;
            % Clear the cell contents.
            Excel.Selection.Clear;
            
            Excel.Range("T20:U1000").Select;
            % Clear the cell contents.
            Excel.Selection.Clear;
            
            Excel.Range("W20:W1000").Select;
            % Clear the cell contents.
            Excel.Selection.Clear;
            
            % Put "cursor" or active cell at A1, the upper left cell.
            Excel.Range('A1').Select;   
            
            Workbook.Save;
            Excel.ScreenUpdating
            Excel.Workbook.Close;
            Quit(Excel)
            delete(Excel) 
            
            %% Write
            xlswrite(NameFile,(1:length(Results_Sce_P_1(:,1)))', 'Biophysical & energy inputs', ['B20:B',num2str(19+length(Results_Sce_P_1(:,1)))])
            xlswrite(NameFile,year(Results.Date), 'Biophysical & energy inputs', ['D20:D',num2str(19+length(Results_Sce_P_1(:,1)))])
            
            xlswrite(NameFile,Results_Sce_R_1(:,2), 'Biophysical & energy inputs', ['O20:O',num2str(19+length(Results_Sce_P_1(:,1)))])
            xlswrite(NameFile,Results_Sce_P_1(:,2), 'Biophysical & energy inputs', ['P20:P',num2str(19+length(Results_Sce_P_1(:,1)))])
            xlswrite(NameFile,Results_Sce_R_1(:,1), 'Biophysical & energy inputs', ['Q20:Q',num2str(19+length(Results_Sce_P_1(:,1)))])
            xlswrite(NameFile,Results_Sce_R_1(:,3), 'Biophysical & energy inputs', ['R20:R',num2str(19+length(Results_Sce_P_1(:,1)))])
            
            xlswrite(NameFile,Results_Sce_R_2(:,2), 'Biophysical & energy inputs', ['T20:T',num2str(19+length(Results_Sce_P_1(:,1)))])
            xlswrite(NameFile,Results_Sce_P_2(:,2), 'Biophysical & energy inputs', ['U20:U',num2str(19+length(Results_Sce_P_1(:,1)))])
            xlswrite(NameFile,Results_Sce_R_2(:,1), 'Biophysical & energy inputs', ['V20:V',num2str(19+length(Results_Sce_P_1(:,1)))])
            xlswrite(NameFile,Results_Sce_R_2(:,3), 'Biophysical & energy inputs', ['W20:W',num2str(19+length(Results_Sce_P_1(:,1)))])
            
            winopen(NameFile)
            
            close(ProgressBar);
                
            msgbox('Ejeuación Exitosa')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Escenario1DropDownLabel = uilabel(app.UIFigure);
            app.Escenario1DropDownLabel.Text = 'Escenario 1';
            app.List_Sce_1 = uidropdown(app.UIFigure);
            app.List_Sce_1.ValueChangedFcn = createCallbackFcn(app, @List_Sce_1ValueChanged, true);
            app.Escenario2DropDown_2Label = uilabel(app.UIFigure);
            app.Escenario2DropDown_2Label.Text = 'Escenario 2';
            app.List_Sce_2 = uidropdown(app.UIFigure);
            app.List_Sce_2.ValueChangedFcn = createCallbackFcn(app, @List_Sce_2ValueChanged, true);
            app.EntradasModeloFinancieroLabel = uilabel(app.UIFigure);
            app.EntradasModeloFinancieroLabel.Text = 'Entradas - Modelo Financiero';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Logo_TNC = uiimage(app.UIFigure);
            app.Logo_TNC.ImageSource = 'Icons_TNC.png';
            app.Logo_CI = uiimage(app.UIFigure);
            app.Logo_CI.ImageSource = 'CI.png';
            app.Logo_Gotta = uiimage(app.UIFigure);
            app.Logo_Gotta.ImageSource = 'Icons_Gotta.jpg';
            app.EjecucinLabel = uilabel(app.UIFigure);
            app.EjecucinLabel.Text = 'Ejecución';
            app.List_Exe_1 = uidropdown(app.UIFigure);
            app.List_Exe_1.ValueChangedFcn = createCallbackFcn(app, @List_Exe_1ValueChanged, true);
            app.List_Exe_1.Enable = 'off';
            app.EjecucinLabel_2 = uilabel(app.UIFigure);
            app.EjecucinLabel_2.Text = 'Ejecución';
            app.List_Exe_2 = uidropdown(app.UIFigure);
            app.List_Exe_2.ValueChangedFcn = createCallbackFcn(app, @List_Exe_2ValueChanged, true);
            app.List_Exe_2.Enable = 'off';
            app.ReservorioLabel = uilabel(app.UIFigure);
            app.ReservorioLabel.Text = 'Reservorio';
            app.List_Place_R = uidropdown(app.UIFigure);
            app.List_Place_R.Enable = 'off';
            app.PuntoLabel = uilabel(app.UIFigure);
            app.PuntoLabel.Text = 'Punto';
            app.List_Place_P = uidropdown(app.UIFigure);
            app.List_Place_P.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_FinModel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end