classdef App_Plot_TimeSeries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Figure                  matlab.ui.control.UIAxes
        Text_Resolution         matlab.ui.control.EditField
        Button_Plot             matlab.ui.control.StateButton
        Switch_Aggregation      matlab.ui.control.Switch
        List_D_NameFun          matlab.ui.control.DropDown
        ResolucinLabel          matlab.ui.control.Label
        List_D_Resolution       matlab.ui.control.DropDown
        EscenarioDropDownLabel  matlab.ui.control.Label
        List_Sce                matlab.ui.control.DropDown
        EjecucinDropDownLabel   matlab.ui.control.Label
        List_Exe                matlab.ui.control.DropDown
        SitiosDropDownLabel     matlab.ui.control.Label
        List_Place              matlab.ui.control.DropDown
        GraficasLabel           matlab.ui.control.Label
        List_D_TypePlot         matlab.ui.control.DropDown
        VariablesDropDownLabel  matlab.ui.control.Label
        List_Var                matlab.ui.control.DropDown
        Button_Clear            matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        NameLegend
        ln
        Data
        PathProject
        StatusPlot = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PathProject)
            app.PathProject = PathProject;
            %NameLegend ln
            
            % Leer carpetas de Scenarios
            d           = dir(fullfile(PathProject,'salidas'));
            dfolders    = d([d(:).isdir]);
            dfolders    = dfolders(~ismember({dfolders(:).name},{'.','..'}));
            Tmp         = {'None',dfolders.name}';
            app.List_Sce.Items = Tmp;
            app.List_Sce.Value = Tmp{1};
            
            app.NameLegend = {};
            app.ln = 0;
            
            % Figure
            title(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            xlabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            ylabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18) 
            xticklabels(app.Figure,'')
            yticklabels(app.Figure,'')
        end

        % Value changed function: Button_Plot
        function Button_PlotValueChanged(app, event)
            
            app.Button_Plot.Value = 0;
            NameVar     = app.List_Var.Value; 
            if strcmp(NameVar,'None')
                f = msgbox('No ha seleccionado una variable valida');    
                set(f, 'color',[1 1 1])  
                return
            end
            
            %% Load Data
            Data        = app.Data;            
            NameLegend  = app.NameLegend;
            ln          = app.ln;
            
            %% Leer Parametros
            TypePlot    = app.List_D_TypePlot.Value;
            CheckAgg    = app.Switch_Aggregation.Value;
            Step        = str2double(app.Text_Resolution.Value);
            NameFun     = app.List_D_NameFun.Value;                      
            Resolution  = app.List_D_Resolution.Value;
            
            NameSce = app.List_Sce.Value;
            NameExe = app.List_Exe.Value;
            NamePlace = app.List_Place.Value;
            
            ln = ln + 1;
            NameLegend{1,ln} = [NameSce,'-',NameExe,'-',NamePlace];            
            app.NameLegend = NameLegend;
            app.ln = ln;
            
            %% Agregación
            if strcmp(CheckAgg,'On')
                if strcmp(Resolution,"Diaria")
                    Results = Data; 
                elseif strcmp(Resolution,"Mensual")
                    Results = Aggregation_TimeSeries(Data, Resolution, NameFun);
                elseif strcmp(Resolution,"Anual")
                    Results = Aggregation_TimeSeries(Data, Resolution, NameFun);
                end
                
            else     
                Results = Aggregation_TimeSeries(Data, Step, NameFun);
            end
            
            %% Buscar Variable
            ii = find(strcmp(Data.NameVar, NameVar));                

            %% Plot
            if strcmp(TypePlot,'Serie de Tiempo')
                if strcmp(app.StatusPlot,'Serie de Tiempo')
                    hold(app.Figure,'on')   
                else                    
                    reset(app.Figure)
                    cla(app.Figure,'reset');
                    app.Figure.BackgroundColor = [1 1 1];
                    hold(app.Figure,'off')
%                     
%                     app.NameLegend = {};
%                     app.ln = 0;
                end
            
                
                %% Plot    
                plot(app.Figure,Results.Date,Results.Data(:,ii),'Linewidth',1.3)
                xlabel(app.Figure,'Tiempo', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,['${',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)   
                title(app.Figure,'Serie de tiempo', 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)    
                
                Leg = legend(app.Figure,NameLegend);
                set(Leg,'Fontsize',8)
                
                % Controla el reinicio de las graficas
                app.StatusPlot = 'Serie de Tiempo';
                
            elseif strcmp(TypePlot,'Curva de Duración')                                
                if strcmp(app.StatusPlot,'Curva de Duración')
                    hold(app.Figure,'on')   
                else                    
                    reset(app.Figure)
                    cla(app.Figure,'reset');
                    app.Figure.BackgroundColor = [1 1 1];
                    hold(app.Figure,'off')                                        
                end
                
                Q               = Results.Data(:,ii); 
                [Por_Q,Qd]      = hist(Q,length(unique(Q)));
                [Qsort, id ]    = sort(Qd, 'descend');
                PQ              = (cumsum(Por_Q(id))/sum(Por_Q(id)))*100;
                [~, id]         = unique(PQ);
                
                Porcentaje      = [10 50 75 85 95];
                DataQ           = interp1(PQ(id), Qsort(id), Porcentaje);
                
                hold(app.Figure,'on')
                
                plot(app.Figure, PQ, Qsort,'Linewidth',1.3);
                hold(app.Figure,'on')
                
                xlabel(app.Figure,'Frecuencia (\%)', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,['${',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)
                title(app.Figure,'Curva de duraci\''on', 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
                
                Leg = legend(app.Figure,NameLegend);
                set(Leg,'Fontsize',8)
                
                % Controla el reinicio de las graficas
                app.StatusPlot = 'Curva de Duración';
                
            elseif strcmp(TypePlot,'Histograma')
                reset(app.Figure)
                cla(app.Figure,'reset');
                app.Figure.BackgroundColor = [1 1 1];
                hold(app.Figure,'off') 
                
                histogram(app.Figure,Results.Data(:,ii),30)
                
                xlabel(app.Figure,'Tiempo', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,'Frecuaenc\''ia', 'interpreter','latex', 'Fontsize',18)
                title(app.Figure,'Histograma', 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
                
                % Controla el reinicio de las graficas
                app.StatusPlot = 'Histograma';
                
            elseif strcmp(TypePlot,'Boxplot')
                reset(app.Figure)
                cla(app.Figure,'reset');
                app.Figure.BackgroundColor = [1 1 1];                
                hold(app.Figure,'off') 
                
                boxplot(app.Figure,Results.Data(:,ii))
                
                xlabel(app.Figure,'Boxplot', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,['${',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)
                title(app.Figure,'Boxplot', 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
                
                % Controla el reinicio de las graficas
                app.StatusPlot = 'Boxplot';
                
            elseif strcmp(TypePlot,'Curva Acumulada')
                reset(app.Figure)
                cla(app.Figure,'reset');
                app.Figure.BackgroundColor = [1 1 1];
                hold(app.Figure,'off') 
                
                Pcm     = cumsum(Results.Data(:,ii), 'omitnan');
                
                % Fit Lineal Model
                Datec   = datenum(Results.Date);
                Params  = polyfit(Datec,Pcm,1);
                Psim    = polyval(Params,Datec);
                
                plot(app.Figure,Results.Date, Pcm,'Linewidth',2)%[0.84 0.23 0.24]
                hold(app.Figure,'on')
                plot(app.Figure,Results.Date,Psim,'--','Linewidth',1, 'color','black')          
                ylim(app.Figure,[min(Pcm) max(Pcm)])
                
                xlabel(app.Figure,'Tiempo', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,['$Accum - {',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)
                title(app.Figure,'Curva Acumulada', 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
                
                % Controla el reinicio de las graficas
                app.StatusPlot = 'Curva Acumulada';
                
            end
            
        end

        % Value changed function: List_Sce
        function List_SceValueChanged(app, event)
            PathProject =  app.PathProject;
            
            value = app.List_Sce.Value;
            
            if ~strcmp(value,'None')                                
                % Listar ejecuciones
                d = dir(fullfile(PathProject,'salidas',value));                    
                dfolders = d([d(:).isdir]);            
                dfolders = dfolders(~ismember({dfolders(:).name},{'.','..'}));
                Tmp      = {'None',dfolders.name}';
                app.List_Exe.Items = Tmp;
                app.List_Exe.Value = Tmp{1};
                
                % Desabilitar
                app.List_Exe.Enable = true;
            else
                % Habilitar
                app.List_Exe.Enable     = false;
                app.List_Place.Enable   = false;
                app.List_Var.Enable     = false;
                
                app.Button_Plot.Enable          = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_Resolution.Enable    = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
            end
            
            value = app.List_Var.Value;
            
        end

        % Value changed function: List_Exe
        function List_ExeValueChanged(app, event)
            PathProject =  app.PathProject;
            
            value = app.List_Exe.Value;
            
            if ~strcmp(value,'None')
                % Listar Series
                NameSce = app.List_Sce.Value;
                NameFiles = dir(fullfile(PathProject,'salidas',NameSce, value,'series','*.txt'));
                NameFiles = {'None',NameFiles.name}';
                
                app.List_Place.Items = NameFiles;
                app.List_Place.Value = NameFiles{1};
                
                % Habilitar
                app.List_Place.Enable = true;
                
            else
                % Desabilitar                
                app.List_Place.Enable   = false;
                app.List_Var.Enable     = false;
                
                app.Button_Clear.Enable         = false;
                app.Button_Plot.Enable          = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_Resolution.Enable    = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
            end
        end

        % Value changed function: List_Place
        function List_PlaceValueChanged(app, event)
            PathProject =  app.PathProject;            
            
            value = app.List_Place.Value;
            
            if ~strcmp(value,'None')
                % leer variables de las salidas
                NameSce = app.List_Sce.Value;
                NameExe = app.List_Exe.Value;
                PathFile = fullfile(PathProject,'salidas',NameSce, NameExe,'series',value);
                
                Data = Read_TimeSeries_SIGA(PathFile);
                app.Data = Data;
                
                app.List_Var.Items = {'None',Data.NameVar{:}}';
                app.List_Var.Value = 'None';
                
                % Habilitar
                app.List_Var.Enable = true;
                
            else
                app.List_Place.Enable           = false;
                app.Button_Clear.Enable         = false;
                app.Button_Plot.Enable          = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_Resolution.Enable    = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
            end
        end

        % Value changed function: List_Var
        function List_VarValueChanged(app, event)
            value = app.List_Var.Value;
            
            if ~strcmp(value,'None')
                app.Button_Clear.Enable         = true;
                app.Button_Plot.Enable          = true;
                app.Switch_Aggregation.Enable   = true;
                app.List_D_Resolution.Enable    = true;
                app.List_D_TypePlot.Enable      = true;                
            else
                app.Button_Clear.Enable         = false;
                app.Button_Plot.Enable          = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_Resolution.Enable    = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
            end
            
        end

        % Value changed function: Switch_Aggregation
        function Switch_AggregationValueChanged(app, event)
            value = app.Switch_Aggregation.Value;
            
            if strcmp(value,'On')
                app.Text_Resolution.Enable      = 0;
                app.List_D_Resolution.Enable    = 1;
                app.List_D_NameFun.Enable       = 0;
                app.List_D_Resolution.Value     = 'Diaria';
            else
                app.Text_Resolution.Enable      = 1;                
                app.List_D_Resolution.Enable    = 0;
                app.List_D_NameFun.Enable       = 1;
            end
        end

        % Value changed function: Button_Clear
        function Button_ClearValueChanged(app, event)
            app.Button_Clear.Value = 0;
            
            reset(app.Figure) 
            cla(app.Figure,'reset');
            app.Figure.BackgroundColor = [1 1 1];
                                   
            app.StatusPlot = 'Clear';
            app.NameLegend = {};
            app.ln = 0;
            
            title(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            xlabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            ylabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18) 
            xticklabels(app.Figure,'')
            yticklabels(app.Figure,'')
            set(app.Figure,'box','on')
        end

        % Value changed function: List_D_TypePlot
        function List_D_TypePlotValueChanged(app, event)
            value = app.List_D_TypePlot.Value;
            
        end

        % Value changed function: List_D_Resolution
        function List_D_ResolutionValueChanged(app, event)
            value = app.List_D_Resolution.Value;
            
            if ~strcmp(value,'Diaria')
                app.List_D_NameFun.Enable = true;
            else
                app.List_D_NameFun.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Figure = uiaxes(app.UIFigure);
            title(app.Figure, 'Title')
            xlabel(app.Figure, 'X')
            ylabel(app.Figure, 'Y')
            app.Figure.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Text_Resolution = uieditfield(app.UIFigure, 'text');
            app.Text_Resolution.Enable = 'off';
            app.Text_Resolution.Value = '2';
            app.Button_Plot = uibutton(app.UIFigure, 'state');
            app.Button_Plot.ValueChangedFcn = createCallbackFcn(app, @Button_PlotValueChanged, true);
            app.Button_Plot.Enable = 'off';
            app.Button_Plot.Icon = 'Icons_TimeSeries_2.png';
            app.Button_Plot.Text = 'Plot';
            app.Switch_Aggregation = uiswitch(app.UIFigure, 'slider');
            app.Switch_Aggregation.ValueChangedFcn = createCallbackFcn(app, @Switch_AggregationValueChanged, true);
            app.Switch_Aggregation.Enable = 'off';
            app.Switch_Aggregation.Value = 'On';
            app.List_D_NameFun = uidropdown(app.UIFigure);
            app.List_D_NameFun.Items = {'Promedio', 'Máximo', 'Mínimo', 'Suma', ''};
            app.List_D_NameFun.Enable = 'off';
            app.List_D_NameFun.Value = 'Promedio';
            app.ResolucinLabel = uilabel(app.UIFigure);
            app.ResolucinLabel.Text = 'Resolución';
            app.List_D_Resolution = uidropdown(app.UIFigure);
            app.List_D_Resolution.Items = {'Diaria', 'Mensual', 'Anual'};
            app.List_D_Resolution.ValueChangedFcn = createCallbackFcn(app, @List_D_ResolutionValueChanged, true);
            app.List_D_Resolution.Enable = 'off';
            app.List_D_Resolution.Value = 'Diaria';
            app.EscenarioDropDownLabel = uilabel(app.UIFigure);
            app.EscenarioDropDownLabel.Text = 'Escenario';
            app.List_Sce = uidropdown(app.UIFigure);
            app.List_Sce.Items = {'None'};
            app.List_Sce.ValueChangedFcn = createCallbackFcn(app, @List_SceValueChanged, true);
            app.List_Sce.Value = 'None';
            app.EjecucinDropDownLabel = uilabel(app.UIFigure);
            app.EjecucinDropDownLabel.Text = 'Ejecución';
            app.List_Exe = uidropdown(app.UIFigure);
            app.List_Exe.Items = {'None'};
            app.List_Exe.ValueChangedFcn = createCallbackFcn(app, @List_ExeValueChanged, true);
            app.List_Exe.Enable = 'off';
            app.List_Exe.Value = 'None';
            app.SitiosDropDownLabel = uilabel(app.UIFigure);
            app.SitiosDropDownLabel.Text = 'Sitios';
            app.List_Place = uidropdown(app.UIFigure);
            app.List_Place.Items = {'None'};
            app.List_Place.ValueChangedFcn = createCallbackFcn(app, @List_PlaceValueChanged, true);
            app.List_Place.Enable = 'off';
            app.List_Place.Value = 'None';
            app.GraficasLabel = uilabel(app.UIFigure);
            app.GraficasLabel.Text = 'Graficas';
            app.List_D_TypePlot = uidropdown(app.UIFigure);
            app.List_D_TypePlot.Items = {'Serie de Tiempo', 'Histograma', 'Curva de Duración', 'Boxplot', 'Curva Acumulada'};
            app.List_D_TypePlot.ValueChangedFcn = createCallbackFcn(app, @List_D_TypePlotValueChanged, true);
            app.List_D_TypePlot.Enable = 'off';
            app.List_D_TypePlot.Value = 'Serie de Tiempo';
            app.VariablesDropDownLabel = uilabel(app.UIFigure);
            app.VariablesDropDownLabel.Text = 'Variables';
            app.List_Var = uidropdown(app.UIFigure);
            app.List_Var.Items = {'None'};
            app.List_Var.ValueChangedFcn = createCallbackFcn(app, @List_VarValueChanged, true);
            app.List_Var.Enable = 'off';
            app.List_Var.Value = 'None';
            app.Button_Clear = uibutton(app.UIFigure, 'state');
            app.Button_Clear.ValueChangedFcn = createCallbackFcn(app, @Button_ClearValueChanged, true);
            app.Button_Clear.Enable = 'off';
            app.Button_Clear.Icon = 'Icons_Clear.png';
            app.Button_Clear.Text = 'limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Plot_TimeSeries(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end