classdef App_SIGA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        Button_OpenProject      matlab.ui.control.StateButton
        Button_Run              matlab.ui.control.StateButton
        RutadeProyectoLabel     matlab.ui.control.Label
        Text_PathProject        matlab.ui.control.EditField
        EscenarioDropDownLabel  matlab.ui.control.Label
        List_Sce                matlab.ui.control.DropDown
        Button_Plot             matlab.ui.control.StateButton
        Button_Configure        matlab.ui.control.StateButton
        Button_FinModel         matlab.ui.control.StateButton
        Logo_TNC                matlab.ui.control.Image
        Logo_CI                 matlab.ui.control.Image
        Logo_Gotta              matlab.ui.control.Image
        SimulacinGeocientficaAbiertaSIGALabel  matlab.ui.control.Label
        StatusRun               matlab.ui.control.Lamp
        Button_Info             matlab.ui.control.StateButton
        Button_Info_2           matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        DialogApp % Dialog box app
    end
    
    properties (Access = public)
        PathProject
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button_OpenProject
        function Button_OpenProjectValueChanged(app, event)
                       
            
            app.Button_OpenProject.Value = 0;
            
            %% Open File
            PathProject = uigetdir('C:\');

            if PathProject == 0
                return
            end
            
            app.Text_PathProject.Value = PathProject;     
            app.PathProject = PathProject;
            
            %% List Executions
            NameSce   = dir(fullfile(PathProject,'entradas','ejecucion','*.txt'));
            
            if isempty(NameSce)
                errordlg('No se encontraron ejecuciones')
                
                app.Button_Plot.Enable      = 0;
                app.List_Sce.Enable         = 0;
                app.Button_FinModel.Enable  = 0;
                app.Button_Configure.Enable = 0;                
                app.Button_Run.Enable       = 0;
                return
            end
            
            %% Asignation list
            NameSce             = {NameSce.name};
            app.List_Sce.Enable = 1;
            NameSce             = {'None',NameSce{:}}';
            app.List_Sce.Items  = NameSce;
            app.List_Sce.Value  = NameSce{1};
            
            app.Button_FinModel.Enable  = 1;
            app.Button_Configure.Enable = 1;
            app.Button_Plot.Enable      = 1;            
            
        end

        % Value changed function: Button_Plot
        function Button_PlotValueChanged(app, event)
           
            app.Button_Plot.Value = 0;            
            PathProject = app.Text_PathProject.Value;
            DialogApp   = App_Plot_TimeSeries(PathProject);
            
        end

        % Value changed function: Button_Configure
        function Button_ConfigureValueChanged(app, event)
            
            app.Button_Configure.Value = 0;
            
            PathProject = app.Text_PathProject.Value;
            NameSce     = app.List_Sce.Value;
            if strcmp(NameSce,'None')
                StatusNew   = true;
            else
                StatusNew   = false;
            end
            DialogApp = App_Execution(StatusNew, PathProject, NameSce);                                  
            
        end

        % Value changed function: Button_FinModel
        function Button_FinModelValueChanged(app, event)
            app.Button_FinModel.Value = 0;
            
            PathProject = app.Text_PathProject.Value;            
            DialogApp = App_FinModel(PathProject);   
            
%             msgbox('Este modulo esta en desarrollo. :)')
        end

        % Value changed function: Button_Run
        function Button_RunValueChanged(app, event)
                       
            try
                app.Button_Run.Value = 0;
                
                PathSIGA = uigetdir('C:\', 'Seleccione la carpeta donde se encuentra el ejecutable de SIGA y el compilador binario de rasters');            
                
                if PathSIGA == 0
                    return
                end
                            
                NameBasin = {'Chivor','Guavio'};
                [indx,~] = listdlg('PromptString',{'Seleccione algoritmo para ejecución de modelo energético',''},...
                    'SelectionMode','single','ListString',NameBasin);
                
                NameBasin   = NameBasin{indx};            
                PathProject = app.Text_PathProject.Value;
                NameFileSce = strsplit(app.List_Sce.Value,'.');                    
                NameFileSce = NameFileSce{1};
                
                app.StatusRun.Color = [0,1,0];
                ProgressBar = waitbar(0, ['Ejecutando SIGA...']);
                wbch        = allchild(ProgressBar);
                jp          = wbch(1).JavaPeer;
                jp.setIndeterminate(1)                                   
                
%                 [status,TextError] = system([PathSIGA,filesep,'SIGA.exe -t "',PathProject,'" -n ',NameFileSce,'.txt -a true'], '-echo');
                [status,TextError] = system('ls');
                
                IdError = strfind(TextError,'Error');
                if status~=0 || ~isempty(IdError)
                    app.StatusRun.Color = [0.8,0.8,0.8];
                    f = msgbox(['SIGA no se ha ejecutado correctamente, por favor verifique que la ruta de la ',...
                        'carpeta de los ejecutables de SIGA sea correcta o que los archivos de configuración ',...
                        'del escenario estén completos (mapas, series, etc.). Le sugerimos revisar el archivo ',...
                        'de ejecución, parámetros y muestreador para descartar cualquier error de configuración.']);
                    set(f, 'color',[1 1 1])
                    
                    close(ProgressBar);
                    return
                end
                
                PathFile = fullfile(PathProject,'entradas','ejecucion',[NameFileSce,'.txt']);
                UserData = Read_ExecutionFile(PathFile);
                    
                Tmp  = dir(fullfile(PathProject,'salidas', UserData.NameSce));
                
                Tmp1 = {Tmp.name}'; Tmp1 = Tmp1(3:end);
                Tmp2 = {Tmp.isdir}';Tmp2 = Tmp2(3:end);
                Tmp3 = {Tmp.date}'; Tmp3 = Tmp3(3:end);
                Tmp3 = cell2mat(cellfun(@datenum,Tmp3, 'UniformOutput',false));
                
                id = Tmp3 == max(Tmp3);
                NameExe = Tmp1{id};
                
                % Ejecutar Codigo Python
                [status,TextError] = Run_EnergyModel( PathProject,UserData.NameSce, NameExe, NameBasin);
                
                IdError = strfind(TextError,'Error');
                if status~=0 || ~isempty(IdError)
                    app.StatusRun.Color = [0.8,0.8,0.8];
                    f = msgbox(['Se presentaron inconvenientes en la determinación de producción energética.',...
                                'Revisar la configuración del archivo de volúmenes útiles']);
                    set(f, 'color',[1 1 1])
                
                    close(ProgressBar);
                    return
                end

                app.StatusRun.Color = [0.8,0.8,0.8];
                
                % Remove Code
                delete(fullfile(PathProject,'salidas', UserData.NameSce, NameExe,'series','Energy.py'))
                
                close(ProgressBar);
                
                msgbox('Ejeuación Exitosa')
            catch
                msgbox(['SIGA no se ha ejecutado correctamente, por favor verifique que la ruta de la ',...
                        'carpeta de los ejecutables de SIGA sea correcta o que los archivos de configuración ',...
                        'del escenario estén completos (mapas, series). Le sugerimos revisar el archivo ',...
                        'de ejecución, parámetros y muestreador para descartar cualquier error de configuración.'])
            end
        end

        % Value changed function: List_Sce
        function List_SceValueChanged(app, event)
            value = app.List_Sce.Value;
            if strcmp(value,'None')
                app.Button_Run.Enable = 0;
            else
                app.Button_Run.Enable = 1;
            end
            PathProject = app.Text_PathProject.Value;
            
            NameSce   = dir(fullfile(PathProject,'entradas','ejecucion','*.txt'));
            
            %% Asignation list
            NameSce = {NameSce.name};
            app.List_Sce.Enable = 1;
            NameSce     = {'None',NameSce{:}}';
            app.List_Sce.Items = NameSce;


        end

        % Value changed function: Button_Info_2
        function Button_Info_2ValueChanged(app, event)
           
            app.Button_Info_2.Value = 0;
            
            % Manual
            system('explorer https://tnc.box.com/s/jlj8oq3tlpz0rjup8fx2sxzn27ry9lly')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Button_OpenProject = uibutton(app.Panel, 'state');
            app.Button_OpenProject.ValueChangedFcn = createCallbackFcn(app, @Button_OpenProjectValueChanged, true);
            app.Button_OpenProject.Icon = 'Icons_Basin.png';
            app.Button_OpenProject.Text = {'Abrir'; 'Cuenca'};
            app.Button_Run = uibutton(app.Panel, 'state');
            app.Button_Run.ValueChangedFcn = createCallbackFcn(app, @Button_RunValueChanged, true);
            app.Button_Run.Enable = 'off';
            app.Button_Run.Icon = 'Icons_Play.png';
            app.Button_Run.Text = {'Ejecutar'; 'Modelo'};
            app.RutadeProyectoLabel = uilabel(app.Panel);
            app.RutadeProyectoLabel.Text = 'Ruta de Proyecto';
            app.Text_PathProject = uieditfield(app.Panel, 'text');
            app.Text_PathProject.Editable = 'off';
            app.Text_PathProject.Value = 'No Project';
            app.EscenarioDropDownLabel = uilabel(app.Panel);
            app.EscenarioDropDownLabel.Text = 'Escenario';
            app.List_Sce = uidropdown(app.Panel);
            app.List_Sce.Items = {'None'};
            app.List_Sce.ValueChangedFcn = createCallbackFcn(app, @List_SceValueChanged, true);
            app.List_Sce.Enable = 'off';
            app.List_Sce.Value = 'None';
            app.Button_Plot = uibutton(app.Panel, 'state');
            app.Button_Plot.ValueChangedFcn = createCallbackFcn(app, @Button_PlotValueChanged, true);
            app.Button_Plot.Enable = 'off';
            app.Button_Plot.Icon = 'Icons_TimeSeries.png';
            app.Button_Plot.Text = 'Plot';
            app.Button_Configure = uibutton(app.Panel, 'state');
            app.Button_Configure.ValueChangedFcn = createCallbackFcn(app, @Button_ConfigureValueChanged, true);
            app.Button_Configure.Enable = 'off';
            app.Button_Configure.Icon = 'Icons_Execution.png';
            app.Button_Configure.Text = {'Configurar'; 'Ejecución'};
            app.Button_FinModel = uibutton(app.Panel, 'state');
            app.Button_FinModel.ValueChangedFcn = createCallbackFcn(app, @Button_FinModelValueChanged, true);
            app.Button_FinModel.Enable = 'off';
            app.Button_FinModel.Icon = 'Icons_ModeloFinaciero.png';
            app.Button_FinModel.Text = {'Modelo'; 'Financiero'};
            app.Logo_TNC = uiimage(app.UIFigure);
            app.Logo_TNC.ImageSource = 'Icons_TNC.png';
            app.Logo_CI = uiimage(app.UIFigure);
            app.Logo_CI.ImageSource = 'CI.png';
            app.Logo_Gotta = uiimage(app.UIFigure);
            app.Logo_Gotta.ImageSource = 'Icons_Gotta.jpg';
            app.SimulacinGeocientficaAbiertaSIGALabel = uilabel(app.UIFigure);
            app.SimulacinGeocientficaAbiertaSIGALabel.Text = 'Simulación  Geocientífica  Abierta - SIGA';
            app.StatusRun = uilamp(app.UIFigure);
            app.Button_Info = uibutton(app.UIFigure, 'state');
            app.Button_Info.Icon = 'Info.png';
            app.Button_Info.Text = '';
            app.Button_Info_2 = uibutton(app.UIFigure, 'state');
            app.Button_Info_2.ValueChangedFcn = createCallbackFcn(app, @Button_Info_2ValueChanged, true);
            app.Button_Info_2.Icon = 'Info.png';
            app.Button_Info_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_SIGA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end